classdef BallsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BallsAppUIFigure    matlab.ui.Figure
        BigCollisionButton  matlab.ui.control.Button
        nEditFieldLabel     matlab.ui.control.Label
        nEditField          matlab.ui.control.NumericEditField
        minREditFieldLabel  matlab.ui.control.Label
        minREditField       matlab.ui.control.NumericEditField
        maxREditFieldLabel  matlab.ui.control.Label
        maxREditField       matlab.ui.control.NumericEditField
        maxVSliderLabel     matlab.ui.control.Label
        maxVSlider          matlab.ui.control.Slider
        Simulate_3DButton   matlab.ui.control.Button
        ha                  matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.BallsAppUIFigure,"center");

            app.ha.XAxis.TickLabels = {};
            app.ha.YAxis.TickLabels = {};

            
            % Display image and stretch to fill axes
            I = imshow('balls.png', 'Parent', app.ha,'XData', [1 app.ha.Position(3)],'YData', [1 app.ha.Position(4)]);
            % Set limits of axes
             app.ha.XLim = [0 I.XData(2)];
             app.ha.YLim = [0 I.YData(2)];
        end

        % Button pushed function: BigCollisionButton
        function BigCollisionButtonPushed(app, event)
            run('CubeCollisionApp.mlapp');
        end

        % Button pushed function: Simulate_3DButton
        function Simulate_3DButtonPushed(app, event)
            n=app.nEditField.Value;
            minR=app.minREditField.Value;maxR=app.maxREditField.Value;
            maxV= app.maxVSlider.Value;
             run('balls.m');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BallsAppUIFigure = uifigure;
            app.BallsAppUIFigure.Name = 'BallsApp';
            app.BigCollisionButton = uibutton(app.BallsAppUIFigure, 'push');
            app.BigCollisionButton.ButtonPushedFcn = createCallbackFcn(app, @BigCollisionButtonPushed, true);
            app.BigCollisionButton.Text = 'Big Collision';
            app.nEditFieldLabel = uilabel(app.BallsAppUIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.BallsAppUIFigure, 'numeric');
            app.nEditField.Value = 6;
            app.minREditFieldLabel = uilabel(app.BallsAppUIFigure);
            app.minREditFieldLabel.Text = 'minR';
            app.minREditField = uieditfield(app.BallsAppUIFigure, 'numeric');
            app.minREditField.Value = 3;
            app.maxREditFieldLabel = uilabel(app.BallsAppUIFigure);
            app.maxREditFieldLabel.Text = 'maxR';
            app.maxREditField = uieditfield(app.BallsAppUIFigure, 'numeric');
            app.maxREditField.Value = 6;
            app.maxVSliderLabel = uilabel(app.BallsAppUIFigure);
            app.maxVSliderLabel.Text = 'maxV';
            app.maxVSlider = uislider(app.BallsAppUIFigure);
            app.maxVSlider.Limits = [0 20];
            app.maxVSlider.Value = 7;
            app.Simulate_3DButton = uibutton(app.BallsAppUIFigure, 'push');
            app.Simulate_3DButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate_3DButtonPushed, true);
            app.Simulate_3DButton.Text = 'Simulate_3D';
            app.ha = uiaxes(app.BallsAppUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BallsApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BallsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BallsAppUIFigure)
        end
    end
end