classdef CubeCollisionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BigCollisionUIFigure  matlab.ui.Figure
        SimulateButton        matlab.ui.control.Button
        xmEditFieldLabel      matlab.ui.control.Label
        xmEditField           matlab.ui.control.NumericEditField
        xREditFieldLabel      matlab.ui.control.Label
        xREditField           matlab.ui.control.NumericEditField
        VEditFieldLabel       matlab.ui.control.Label
        VEditField            matlab.ui.control.NumericEditField
        ha                    matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.BigCollisionUIFigure,"center");
            
            %static image in 2DAxis
            app.ha.XAxis.TickLabels = {};
            app.ha.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('bigSphere.png', 'Parent', app.ha,'XData', [1 app.ha.Position(3)],'YData', [1 app.ha.Position(4)]);
            % Set limits of axes
             app.ha.XLim = [0 I.XData(2)];
             app.ha.YLim = [0 I.YData(2)];
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            xm=app.xmEditField.Value;
            xR=   app.xREditField.Value;
            v=  app.VEditField.Value;
            run('CubeCollision.m');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BigCollisionUIFigure = uifigure;
            app.BigCollisionUIFigure.Name = 'Big Collision';
            app.SimulateButton = uibutton(app.BigCollisionUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.xmEditFieldLabel = uilabel(app.BigCollisionUIFigure);
            app.xmEditFieldLabel.Text = 'xm';
            app.xmEditField = uieditfield(app.BigCollisionUIFigure, 'numeric');
            app.xmEditField.Value = 1000;
            app.xREditFieldLabel = uilabel(app.BigCollisionUIFigure);
            app.xREditFieldLabel.Text = 'xR';
            app.xREditField = uieditfield(app.BigCollisionUIFigure, 'numeric');
            app.xREditField.Value = 10;
            app.VEditFieldLabel = uilabel(app.BigCollisionUIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.BigCollisionUIFigure, 'numeric');
            app.VEditField.Value = 20;
            app.ha = uiaxes(app.BigCollisionUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = CubeCollisionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BigCollisionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BigCollisionUIFigure)
        end
    end
end