classdef MAAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAANUIFigure      matlab.ui.Figure
        ProjectileButton  matlab.ui.control.Button
        CollisionButton   matlab.ui.control.Button
        ha                matlab.ui.control.UIAxes
        haha              matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             movegui(app.MAANUIFigure,"center");
             
            app.ha.XAxis.TickLabels = {};
            app.ha.YAxis.TickLabels = {};

            
            % Display image and stretch to fill axes
            I = imshow('wall1.png', 'Parent', app.ha,'XData', [1 app.ha.Position(3)],'YData', [1 app.ha.Position(4)]);
            % Set limits of axes
             app.ha.XLim = [0 I.XData(2)];
             app.ha.YLim = [0 I.YData(2)];
             
            app.haha.XAxis.TickLabels = {};
            app.haha.YAxis.TickLabels = {};

            
            % Display image and stretch to fill axes
            I = imshow('wall2.jpg', 'Parent', app.haha,'XData', [1 app.haha.Position(3)],'YData', [1 app.haha.Position(4)]);
            % Set limits of axes
             app.haha.XLim = [0 I.XData(2)];
             app.haha.YLim = [0 I.YData(2)];
        end

        % Button pushed function: ProjectileButton
        function ProjectileButtonPushed(app, event)
            run('ProjectleApp.mlapp')
        end

        % Callback function
        function CollisionButtonPushed(app, event)
            run('BallsApp.mlapp')
        end

        % Callback function
        function GameButtonPushed(app, event)
            run('gameBuild.m')
        end

        % Button pushed function: CollisionButton
        function CollisionButtonPushed2(app, event)
            run('BallsApp.mlapp');
            
        end

        % Callback function
        function GAMEButtonPushed(app, event)

        end

        % Close request function: MAANUIFigure
        function MAANUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAANUIFigure = uifigure;
            app.MAANUIFigure.Name = 'MAAN';
            app.MAANUIFigure.CloseRequestFcn = createCallbackFcn(app, @MAANUIFigureCloseRequest, true);
            app.ProjectileButton = uibutton(app.MAANUIFigure, 'push');
            app.ProjectileButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectileButtonPushed, true);
            app.ProjectileButton.Text = 'Projectile';
            app.CollisionButton = uibutton(app.MAANUIFigure, 'push');
            app.CollisionButton.ButtonPushedFcn = createCallbackFcn(app, @CollisionButtonPushed2, true);
            app.CollisionButton.Text = 'Collision';
            app.ha = uiaxes(app.MAANUIFigure);
            app.haha = uiaxes(app.MAANUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MAAN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAANUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAANUIFigure)
        end
    end
end