classdef ProjectleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjectileSimulationUIFigure  matlab.ui.Figure
        X0EditFieldLabel              matlab.ui.control.Label
        X0EditField                   matlab.ui.control.EditField
        Y0EditFieldLabel              matlab.ui.control.Label
        Y0EditField                   matlab.ui.control.EditField
        Z0EditFieldLabel              matlab.ui.control.Label
        Z0EditField                   matlab.ui.control.EditField
        kEditFieldLabel               matlab.ui.control.Label
        kEditField                    matlab.ui.control.EditField
        tEditFieldLabel               matlab.ui.control.Label
        tEditField                    matlab.ui.control.EditField
        V0yEditField_2Label           matlab.ui.control.Label
        V0yEditField_2                matlab.ui.control.EditField
        V0zEditField_2Label           matlab.ui.control.Label
        V0zEditField_2                matlab.ui.control.EditField
        V0xEditField_2Label           matlab.ui.control.Label
        V0xEditField_2                matlab.ui.control.EditField
        gEditFieldLabel               matlab.ui.control.Label
        gEditField                    matlab.ui.control.EditField
        REditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.EditField
        Simulate_3DButton             matlab.ui.control.Button
        mEditFieldLabel               matlab.ui.control.Label
        mEditField                    matlab.ui.control.EditField
        ha                            matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             movegui(app.ProjectileSimulationUIFigure,"center");
            %static image in 2DAxis
            app.ha.XAxis.TickLabels = {};
            app.ha.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('projectile.png', 'Parent', app.ha,'XData', [1 app.ha.Position(3)],'YData', [1 app.ha.Position(4)]);
            % Set limits of axes
             app.ha.XLim = [0 I.XData(2)];
             app.ha.YLim = [0 I.YData(2)];
        end

        % Button pushed function: Simulate_3DButton
        function ButtonPushed(app, event)
             x0=str2double(app.X0EditField.Value);y0=str2double(app.Y0EditField.Value);z0=str2double(app.Z0EditField.Value);
            v0x=str2double(app.V0xEditField_2.Value);v0y=str2double(app.V0yEditField_2.Value);v0z=str2double(app.V0zEditField_2.Value);
            g=str2double(app.gEditField.Value);R=str2double(app.REditField.Value);tmax=str2double(app.tEditField.Value);tmin=0;
            m=str2double(app.mEditField.Value);k=str2double(app.kEditField.Value);
            run("Projectile.m")
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjectileSimulationUIFigure = uifigure;
            app.ProjectileSimulationUIFigure.Name = 'Projectile Simulation';
            app.X0EditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.X0EditField.Value = '1';
            app.Y0EditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.Y0EditField.Value = '1';
            app.Z0EditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.Z0EditFieldLabel.Text = 'Z0';
            app.Z0EditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.Z0EditField.Value = '1';
            app.kEditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.kEditField.Value = '0.1';
            app.tEditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.tEditField.Value = '6';
            app.V0yEditField_2Label = uilabel(app.ProjectileSimulationUIFigure);
            app.V0yEditField_2Label.Text = 'V0y';
            app.V0yEditField_2 = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.V0yEditField_2.Value = '3';
            app.V0zEditField_2Label = uilabel(app.ProjectileSimulationUIFigure);
            app.V0zEditField_2Label.Text = 'V0z';
            app.V0zEditField_2 = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.V0zEditField_2.Value = '30';
            app.V0xEditField_2Label = uilabel(app.ProjectileSimulationUIFigure);
            app.V0xEditField_2Label.Text = 'V0x';
            app.V0xEditField_2 = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.V0xEditField_2.Value = '6';
            app.gEditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.gEditField.Value = '10';
            app.REditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.REditField.Value = '2';
            app.Simulate_3DButton = uibutton(app.ProjectileSimulationUIFigure, 'push');
            app.Simulate_3DButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Simulate_3DButton.Text = 'Simulate_3D';
            app.mEditFieldLabel = uilabel(app.ProjectileSimulationUIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.ProjectileSimulationUIFigure, 'text');
            app.mEditField.Value = '1';
            app.ha = uiaxes(app.ProjectileSimulationUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectleApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjectileSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjectileSimulationUIFigure)
        end
    end
end